classdef ChabocheParamFinder_3X_Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChabocheParamFinder_3X_UIFigure  matlab.ui.Figure
        SaveResultsButton            matlab.ui.control.Button
        ResultsPanel                 matlab.ui.container.Panel
        g2EditField                  matlab.ui.control.NumericEditField
        g2EditFieldLabel             matlab.ui.control.Label
        C2EditField                  matlab.ui.control.NumericEditField
        C2EditFieldLabel             matlab.ui.control.Label
        X3_0EditField                matlab.ui.control.NumericEditField
        X3_0EditFieldLabel           matlab.ui.control.Label
        C3EditField                  matlab.ui.control.NumericEditField
        C3EditFieldLabel             matlab.ui.control.Label
        g1EditField                  matlab.ui.control.NumericEditField
        g1EditFieldLabel             matlab.ui.control.Label
        C1EditField                  matlab.ui.control.NumericEditField
        C1EditFieldLabel             matlab.ui.control.Label
        sigma0EditField              matlab.ui.control.NumericEditField
        sigma0EditFieldLabel         matlab.ui.control.Label
        IdentifyParametersButton     matlab.ui.control.Button
        ExpCycle3Panel               matlab.ui.container.Panel
        ClearDataButton_3            matlab.ui.control.Button
        ImportDataButton_3           matlab.ui.control.Button
        CurrentFileEditField_3       matlab.ui.control.EditField
        CurrentFileEditField_3Label  matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        ExpCycle2Panel               matlab.ui.container.Panel
        ClearDataButton_2            matlab.ui.control.Button
        ImportDataButton_2           matlab.ui.control.Button
        CurrentFileEditField_2       matlab.ui.control.EditField
        CurrentFileEditField_2Label  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        ExpCycle1Panel               matlab.ui.container.Panel
        ClearDataButton_1            matlab.ui.control.Button
        CurrentFileEditField_1       matlab.ui.control.EditField
        CurrentFileEditFieldLabel    matlab.ui.control.Label
        ImportDataButton_1           matlab.ui.control.Button
        UIAxes_1                     matlab.ui.control.UIAxes
        ChabocheParameterFinder3BackstressesRegularizedLSQLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        sigma_sim_1 = []; % Simulated Stabilized Cycle (Sigma)
        e_sim_1 = []; % Simulated Stabilized Cycle (Epsilon)
        sigma_stab_1 = []; % Imported Stabilized Cycle (Sigma)
        e_stab_1 = []; % Imported Stabilized Cycle (Epsilon)
        e_pl_stab_1 = []; % Imported Stabilized Cycle (Plastic Epsilon)
        delta_e_pl_stab_1 = []; % Imported Delta Plastic Epsilon
        R_stab_1 = []; % Imported R
        sum_sigma_stab_1 = []; % Imported Sum of Stress Extreme Values
        delta_sigma_stab_1 = []; % Imported Delta of Stress Extreme Values
        area_stab_1 = []; % Imported Plastic Work
        der_stab_1 = []; % Imported Slope at Cycle Extremities
        sigmaY02_1 = []; % Imported Yield-Strength at 0.2%
        
        sigma_sim_2 = []; % Simulated Stabilized Cycle (Sigma)
        e_sim_2 = []; % Simulated Stabilized Cycle (Epsilon)
        sigma_stab_2 = []; % Imported Stabilized Cycle (Sigma)
        e_stab_2 = []; % Imported Stabilized Cycle (Epsilon)
        e_pl_stab_2 = []; % Imported Stabilized Cycle (Plastic Epsilon)
        delta_e_pl_stab_2 = []; % Imported Delta Plastic Epsilon
        R_stab_2 = []; % Imported R
        sum_sigma_stab_2 = []; % Imported Sum of Stress Extreme Values
        delta_sigma_stab_2 = []; % Imported Delta of Stress Extreme Values
        area_stab_2 = []; % Imported Plastic Work
        der_stab_2 = []; % Imported Slope at Cycle Extremities
        sigmaY02_2 = []; % Imported Yield-Strength at 0.2%
        
        e_sim_rat = []; % Simulated Ratcheting (Max Epsilon)
        sigma_rat = []; % Imported Ratcheting (Sigma)
        e_rat = []; % Imported Ratcheting (Epsilon)
        e_pl_rat = []; % Imported Ratcheting (Plastic Epsilon)
        cycle_rat = []; % Imported Cycle Number
        max_sigma_rat = []; % Imported Max Sigma
        min_sigma_rat = []; % Imported Min Sigma
        max_e_rat = [], % Imported Max Epsilon
        max_e_pl_rat = [], % Imported Max Epsilon
        delta_e_pl_av_rat = []; % Imported Delta Average Plastic Epsilon
        delta_e_pl_s_rat = []; % Imported Delta Shift Plastic Epsilon
        sigma_av_rat = []; % Imported Mean Stress

        Eyoung = []; % Young Modulus
    end

    methods (Access = private)
    
        function StabSim(app,WinNum)
            npoints = 100;
            
            % Get Values
            C1 = app.C1EditField.Value;
            g1 = app.g1EditField.Value;
            s1 = 0;
            C2 = app.C2EditField.Value;
            g2 = app.g2EditField.Value;
            s2 = 0;
            C3 = app.C3EditField.Value;
            g3 = 0;
            s3 = app.X3_0EditField.Value;
            
            E = app.Eyoung;
            sigma0 = app.sigma0EditField.Value;
            
            switch WinNum
                case 1
                    delta_ep = app.delta_e_pl_stab_1;
                    R = app.R_stab_1;
                case 2
                    delta_ep = app.delta_e_pl_stab_2;
                    R = app.R_stab_2;
            end
                    
            max_ep = delta_ep/(1-R);
            min_ep = max_ep-delta_ep;
                    
            ep_up = linspace(min_ep,max_ep,npoints);
            ep_down = linspace(max_ep,min_ep,npoints);
            
            % Get extremal backstresses and integrate (1)
            if g1 == 0
                X1m = C1*min_ep+s1;
                X1M = C1*max_ep+s1;
                
                X1_up = s1+C1.*ep_up;
                X1_down = s1+C1.*ep_down;
            else
                X1m = -(C1/g1)*(1-exp(-g1*delta_ep))/(1+exp(-g1*delta_ep));
                X1M = -X1m;
                
                X1_up = (C1/g1) + (X1m - C1/g1)*exp(-g1*abs(ep_up-min_ep));
                X1_down = -(C1/g1) + (X1M + C1/g1)*exp(-g1*abs(ep_down-max_ep));
            end
            
            % Get extremal backstresses and integrate (2)
            if g2 == 0
                X2m = C2*min_ep+s2;
                X2M = C2*max_ep+s2;
                
                X2_up = s2+C2.*ep_up;
                X2_down = s2+C2.*ep_down;
            else
                X2m = -(C2/g2)*(1-exp(-g2*delta_ep))/(1+exp(-g2*delta_ep));
                X2M = -X2m;
                
                X2_up = (C2/g2) + (X2m - C2/g2)*exp(-g2*abs(ep_up-min_ep));
                X2_down = -(C2/g2) + (X2M + C2/g2)*exp(-g2*abs(ep_down-max_ep));
            end
            
            % Get extremal backstresses and integrate (3)
            if g3 == 0
                X3m = C3*min_ep+s3;
                X3M = C3*max_ep+s3;
                
                X3_up = s3+C3.*ep_up;
                X3_down = s3+C3.*ep_down;
            else
                X3m = -(C3/g3)*(1-exp(-g3*delta_ep))/(1+exp(-g3*delta_ep));
                X3M = -X3m;
                
                X3_up = (C3/g3) + (X3m - C3/g3)*exp(-g3*abs(ep_up-min_ep));
                X3_down = -(C3/g3) + (X3M + C3/g3)*exp(-g3*abs(ep_down-max_ep));
            end
            
            % Build stresses
            sigma_start = -sigma0+X1m+X2m+X3m;
            sigma_up = sigma0 + X1_up + X2_up + X3_up;
            sigma_down = -sigma0 + X1_down + X2_down + X3_down;
            
            % Build strains
            e_start = min_ep + sigma_start/E;
            e_up = ep_up + sigma_up/E;
            e_down = ep_down + sigma_down/E;

            % Store variables and Plot results
            switch WinNum
                case 1
                    app.sigma_sim_1 = [sigma_start, sigma_up, sigma_down];
                    app.e_sim_1 = [e_start, e_up, e_down];
                    
                    cla(app.UIAxes_1)
                    plot(app.UIAxes_1,[app.e_stab_1; app.e_stab_1(1)],[app.sigma_stab_1; app.sigma_stab_1(1)],'Color',[0 0.45 0.74],'LineWidth',2)
                    hold(app.UIAxes_1,'on')
                    plot(app.UIAxes_1,app.e_sim_1,app.sigma_sim_1,'Color',[0.64 0.08 0.18],'LineWidth',2);
                    hold(app.UIAxes_1,'off')
                case 2
                    app.sigma_sim_2 = [sigma_start, sigma_up, sigma_down];
                    app.e_sim_2 = [e_start, e_up, e_down];
                    
                    cla(app.UIAxes_2)
                    plot(app.UIAxes_2,[app.e_stab_2; app.e_stab_2(1)],[app.sigma_stab_2; app.sigma_stab_2(1)],'Color',[0 0.45 0.74],'LineWidth',2)
                    hold(app.UIAxes_2,'on')
                    plot(app.UIAxes_2,app.e_sim_2,app.sigma_sim_2,'Color',[0.64 0.08 0.18],'LineWidth',2);
                    hold(app.UIAxes_2,'off')
            end
        end
        
        function [sigma_stab,e_stab,e_pl_stab,delta_sigma_stab,sum_sigma_stab,delta_e_pl_stab,R_stab,area_stab,der_stab,sigmaY02] = StabExtractor(app,sigma_clean,e_clean,e_pl,cycle_clean,maxvec_s,minvec_s,cyclevec)
            % --- PARAMETERS ---
            
            % Threshold for cycle exclusion
            thres = 0.5; % Ratio wrt Max Stress
            % We assume that under that ratio the specimen is breaking, so we discard subsequent data points
            
            % Threshold for peak amplitude variation to state that the cycle is stable
            dthres = 0.1; % [MPa/Cycle]
            
            % --- --- --- ---
            
            % Regularization Tools
            addpath('RegTools')
            
            if cyclevec == 1 % We already have a stabilized cycle
                sigma_stab = sigma_clean;
                e_stab = e_clean;
                e_pl_stab = e_pl;
            else
                % First value to be excluded...
                Mout = find(abs(maxvec_s)<thres*max(abs(maxvec_s)),1);
                mout = find(abs(minvec_s)<thres*max(abs(minvec_s)),1);
                
                % ...and its default value (length + 1)
                Mlen = length(maxvec_s)+1;
                mlen = length(minvec_s)+1;
                
                len = min([Mlen,mlen,Mout,mout])-1;
                
                if cyclevec(len)==cyclevec(len-1)
                    len = len-1;
                end
                
                % Crop
                maxvec_s = maxvec_s(1:len);
                minvec_s = minvec_s(1:len);
                cyclevec = cyclevec(1:len);
                
                % Numerical derivative length
                lend = len-1;
                
                % GCV regularized derivative
                
                A = eye(lend);
                c = get_l(lend,2); % Second-order Tikhonov regularization
                
                [U,sm,~,~,~] = cgsvd(A,c);
                alfa_Ms = gcv(U,sm,(diff(maxvec_s)./diff(cyclevec))')^2;
                alfa_ms = gcv(U,sm,(diff(minvec_s)./diff(cyclevec))')^2;
                
                % Filtered derivatives
                dmaxvec_s_filt = pinv((A'*A) + alfa_Ms*(c'*c))*A'*(diff(maxvec_s)./diff(cyclevec))';
                dminvec_s_filt = pinv((A'*A) + alfa_ms*(c'*c))*A'*(diff(minvec_s)./diff(cyclevec))';
                
                relaxed = false;
                while true 
                    % Look for the latest acceptable cycle...
                    ind_max = find(abs(dmaxvec_s_filt)<dthres);
                    ind_min = find(abs(dminvec_s_filt)<dthres);
                
                    ind = intersect(ind_max,ind_min);
                    
                    % ...or increase threshold if it does not exist
                    if isempty(ind)
                        dthres = dthres + 0.1;
                        relaxed = true;
%                     elseif length(ind)>1 && ~relaxed
%                         dthres = dthres*0.9;
                    else
                        break;
                    end
                end
                stab_ind = ind(end);
%                 stab_ind = ind(1);
                
                % Results
                sigma_stab = sigma_clean(cycle_clean==cyclevec(stab_ind));
                e_stab = e_clean(cycle_clean==cyclevec(stab_ind));
                e_pl_stab = e_pl(cycle_clean==cyclevec(stab_ind));
            end
            
            max_sigma_stab = max(sigma_stab);
            min_sigma_stab = min(sigma_stab);
            max_e_pl_stab = max(e_pl_stab);
            min_e_pl_stab = min(e_pl_stab);
            delta_sigma_stab = max_sigma_stab - min_sigma_stab;
            sum_sigma_stab = max_sigma_stab + min_sigma_stab;
            delta_e_pl_stab = max_e_pl_stab - min_e_pl_stab;
            R_stab = min_e_pl_stab/max_e_pl_stab;
            
            zci = @(v) find(diff(sign(v)));
            
            sigmaY02up = max(sigma_stab(zci(e_pl_stab-min_e_pl_stab-0.002)))-min_sigma_stab;
            sigmaY02down = max_sigma_stab-min(sigma_stab(zci(max_e_pl_stab-e_pl_stab-0.002)));
%             sigmaY02up = max(sigma_stab(zci(e_pl_stab-min_e_pl_stab-0.001)))-min_sigma_stab;
%             sigmaY02down = max_sigma_stab-min(sigma_stab(zci(max_e_pl_stab-e_pl_stab-0.001)));
            
            sigmaY02 = (sigmaY02up+sigmaY02down)/2;
            
            area_stab = polyarea(e_pl_stab,sigma_stab);
            
            % Regolarizzazione GCV della derivata di sigma rispetto a e_pl all'estremità
            
            if sigma_stab(2)-sigma_stab(1) > 0 % Rampa crescente
                first_increasing = true;
                ind_tgt = find(sigma_stab==max_sigma_stab,1);
            else
                first_increasing = false;
                ind_tgt = find(sigma_stab==min_sigma_stab,1);
            end
            
            sigma_2der = sigma_stab(1:ind_tgt);
            e_2der = e_stab(1:ind_tgt);
            
%             lens = length(sigma_2der)-1;
%             
%             As = eye(lens);
%             cs = get_l(lens,2);
%             
%             [Us,sms,~,~,~] = cgsvd(As,cs);
%             alfa_stab = gcv(Us,sms,diff(sigma_2der)./diff(e_2der))^2;
%             dsigma_filt = pinv((As'*As) + alfa_stab*(cs'*cs))*As'*(diff(sigma_2der)./diff(e_2der));
% 
%             der_stab_tot = dsigma_filt(end);

            p = polyfit(e_2der(end-11:end-1),sigma_2der(end-11:end-1),1);
            
%             % L'altra rampa
%             if first_increasing % Rampa decrescente
%                 ind_tgt2 = find(sigma_stab==min_sigma_stab,1);
%             else
%                 ind_tgt2 = find(sigma_stab==max_sigma_stab,1);
%             end
%             
%             sigma_2der = sigma_stab(ind_tgt+1:ind_tgt2);
%             e_2der = e_stab(ind_tgt+1:ind_tgt2);
% 
%             p2 = polyfit(e_2der(end-11:end-1),sigma_2der(end-11:end-1),1);
            
            der_stab_tot = p(1);
%             der_stab_tot = (p(1)+p2(1))/2;

            der_stab = app.Eyoung*der_stab_tot./(app.Eyoung-der_stab_tot);
        end
        
        function params = ParamIdentify(app)
            params = zeros(7,1);
            condlimitA = 500; % Maximum condition number of matrix A
            
            delta_e_pl_stab_vec = [app.delta_e_pl_stab_1; app.delta_e_pl_stab_2];
            delta_sigma_stab_vec = [app.delta_sigma_stab_1; app.delta_sigma_stab_2];
            sum_sigma_stab_vec = [app.sum_sigma_stab_1; app.sum_sigma_stab_2];
            area_stab_vec = [app.area_stab_1; app.area_stab_2];
            der_stab_vec = [app.der_stab_1; app.der_stab_2];
            R_stab_vec = [app.R_stab_1; app.R_stab_2];
            
            gamma_trial = 10:1500;
            params_mat = zeros(6,length(gamma_trial));
            sigma0trial = zeros(length(gamma_trial),1);
            
            nEq = length(R_stab_vec);
            
            if nEq == 2
                A = zeros(nEq,2);
                
                A(:,1) = 2;
                A(:,2) = delta_e_pl_stab_vec.*(1+R_stab_vec)./(1-R_stab_vec);
                
                b = sum_sigma_stab_vec;
                
                if cond(A) < condlimitA
                    params_mat(1:2,:) = repmat(pinv(A)*b,1,size(params_mat,2));
                else
                    params_mat(2,:) = 0;
                    A = A(:,1);
                    params_mat(1,:) = repmat(pinv(A)*b,1,size(params_mat,2));
                end
                
                for i=1:length(gamma_trial)
                    k1 = der_stab_vec(1)-params_mat(2,i);
                    k2 = der_stab_vec(2)-params_mat(2,i);
                    
                    a1 = 1-tanh(gamma_trial(i)*delta_e_pl_stab_vec(1)/2);
                    a2 = 1-tanh(gamma_trial(i)*delta_e_pl_stab_vec(2)/2);

                    params_mat(4,i) = gamma_trial(i);
                    params_mat(6,i) = 1e-3;
                    
                    params_mat(3,i) = (k1-k2)/(a1-a2);
                    params_mat(5,i) = (a1*k2-a2*k1)/(a1-a2);
                    
                    sigma0_1 = (delta_sigma_stab_vec(1)-((2*params_mat(3,i)/params_mat(4,i))*tanh(params_mat(4,i)*delta_e_pl_stab_vec(1)/2) + (params_mat(5,i)+params_mat(2,i))*delta_e_pl_stab_vec(1)))/2;
                    sigma0_2 = (delta_sigma_stab_vec(2)-((2*params_mat(3,i)/params_mat(4,i))*tanh(params_mat(4,i)*delta_e_pl_stab_vec(2)/2) + (params_mat(5,i)+params_mat(2,i))*delta_e_pl_stab_vec(2)))/2;
   
                    sigma0trial(i) = (sigma0_1+sigma0_2)/2;
                end
            else
                msg = msgbox({'At least two stabilized cycles are needed!'},'Error','error');
                msgboxFontSize(msg,12);
                drawnow;
            end
            
            SqErr = zeros(size(gamma_trial));
            
            for i=1:length(gamma_trial)
                if all(params_mat(3:6,i)>=0) && sigma0trial(i)>=0
                    res = ChabocheTgtFun_3X_Final([params_mat(3:6,i);sigma0trial(i)],params_mat(2,i),delta_e_pl_stab_vec,delta_sigma_stab_vec,area_stab_vec);
                    SqErr(i) = sum(res.^2);
                else
                    SqErr(i) = +Inf;
                end
                
            end
            
            [minerr,inderr] = min(SqErr);
            params = [params_mat(:,inderr);sigma0trial(inderr)];

            if ~isempty(app.cycle_rat)
                len = length(app.max_e_pl_rat);
                lend = len-1;
                
                A = eye(lend);
                c = get_l(lend,2); % Regolarizzazione Tikhonov Secondo ordine
                
                % [U,sm] = cgsvd(A,c);
                % alfa_Ms = min([gcv(U,sm,diff(maxvec_e_pl)')^2,alfa_max_gcv]);
                alfa = 1000;
                dmaxvec_e_pl_filt = pinv((A'*A) + alfa*(c'*c))*A'*diff(app.max_e_pl_rat)';
                
                zci = @(v) find(diff(sign(v)));
                % [~,indminslope] = min(abs(dmaxvec_e_pl_filt));
                zc = zci(diff(dmaxvec_e_pl_filt));
                indminslope = zc(end);
                dmaxvec_temp = dmaxvec_e_pl_filt(1:indminslope);
                [~,indmaxslope] = max(abs(dmaxvec_temp));
%                 indtgt = floor((indminslope+indmaxslope)/2);
                indtgt = floor(indminslope*0.75);
                delta_e_pl_r = dmaxvec_e_pl_filt(indtgt);
                delta_e_pl_s = delta_e_pl_r/2;
                delta_e_pl_av = app.delta_e_pl_av_rat(indtgt);
                
                params(6) = asinh(((app.sigma_av_rat/delta_e_pl_s - params(3)/sinh(params(4)*delta_e_pl_av))^-1)*params(5))/delta_e_pl_av;
                
                res = ChabocheTgtFun_3X_Final(params(3:end),params(2),delta_e_pl_stab_vec,delta_sigma_stab_vec,area_stab_vec);
                minerr = sum(res.^2);
            end
            
            disp('Minimum Cumulative RMS error = ')
            disp(sqrt(minerr))
            
        end
        
        function resetParam(app,WinNum)
            app.sigma_sim_1 = [];
            app.e_sim_1 = [];
            app.sigma_sim_2 = [];
            app.e_sim_2 = [];
            
            switch WinNum
                case 1
                    app.sigma_stab_1 = [];
                    app.e_stab_1 = [];
                    app.delta_e_pl_stab_1 = [];
                    app.R_stab_1 = [];
                    app.sum_sigma_stab_1 = [];
                    app.delta_sigma_stab_1 = [];
                    app.area_stab_1 = [];
                    app.der_stab_1 = [];
                    app.sigmaY02_1 = [];
                    app.CurrentFileEditField_1.Value = '';
                case 2
                    app.sigma_stab_2 = [];
                    app.e_stab_2 = [];
                    app.delta_e_pl_stab_2 = [];
                    app.R_stab_2 = [];
                    app.sum_sigma_stab_2 = [];
                    app.delta_sigma_stab_2 = [];
                    app.area_stab_2 = [];
                    app.der_stab_2 = [];
                    app.sigmaY02_2 = [];
                    app.CurrentFileEditField_2.Value = '';
                case 3
                    app.e_sim_rat = [];
                    app.sigma_rat = [];
                    app.e_rat = [];
                    app.e_pl_rat = [];
                    app.cycle_rat = [];
                    app.max_sigma_rat = [];
                    app.min_sigma_rat = [];
                    app.max_e_rat = [];
                    app.max_e_pl_rat = [];
                    app.delta_e_pl_av_rat = [];
                    app.delta_e_pl_s_rat = [];
                    app.sigma_av_rat = [];
                    app.CurrentFileEditField_3.Value = '';
            end
            
            app.C1EditField.Value = 0;
            app.g1EditField.Value = 0;
            app.C2EditField.Value = 0;
            app.g2EditField.Value = 0;
            app.C3EditField.Value = 0;
            app.X3_0EditField.Value = 0;
            app.sigma0EditField.Value = 0;
        end
        
        
        function RatchSim(app)
            
            % Get Values
            C1 = app.C1EditField.Value;
            g1 = app.g1EditField.Value;
            X1 = 0;
            C2 = app.C2EditField.Value;
            g2 = app.g2EditField.Value;
            X2 = 0;
            C3 = app.C3EditField.Value;
            g3 = 0;
            X3 = app.X3_0EditField.Value;
            
            sigma0 = app.sigma0EditField.Value;
            E = app.Eyoung;
            
            sigma_max = app.max_sigma_rat;
            sigma_min = app.min_sigma_rat;
            
            elastic = false;
            if sigma_max-sigma_min < 2*sigma0
                elastic = true;
            end
            
            Ncycles = 100;
            ep_step = 1e-5;
            
            current_ep = 0;
            ep_thres = 0.10;
            cycle = 1;

            ep_max_vec = zeros(Ncycles,1);
            ep_min_vec = zeros(Ncycles,1);
            X1_max_vec = zeros(Ncycles,1);
            X1_min_vec = zeros(Ncycles,1);
            X2_max_vec = zeros(Ncycles,1);
            X2_min_vec = zeros(Ncycles,1);
            X3_max_vec = zeros(Ncycles,1);
            X3_min_vec = zeros(Ncycles,1);
            
            X1_curr = X1;
            X2_curr = X2;
            X3_curr = X3;
            
            up = true;
        
            while cycle < Ncycles
                if current_ep>ep_thres
                    break;
                end
                if up  
                    if g1 ~= 0
                        X1fun = @(ep) C1/g1 + (X1_curr-(C1/g1))*exp(-g1*abs(ep-current_ep));
                    else
                        X1fun = @(ep) X1_curr + C1*(ep-current_ep);
                    end
                    if g2 ~= 0
                        X2fun = @(ep) C2/g2 + (X2_curr-(C2/g2))*exp(-g2*abs(ep-current_ep));
                    else
                        X2fun = @(ep) X2_curr + C2*(ep-current_ep);
                    end
                    if g3 ~= 0
                        X3fun = @(ep) C3/g3 + (X3_curr-(C3/g3))*exp(-g3*abs(ep-current_ep));
                    else
                        X3fun = @(ep) X3_curr + C3*(ep-current_ep);
                    end
                    
                    tgt_fun = @(ep) X1fun(ep)+X2fun(ep)+X3fun(ep)+sigma0-sigma_max;
                    if tgt_fun(current_ep)*tgt_fun(10)<0
                        new_ep = fzero(tgt_fun,[current_ep,10]);
                        
                        current_ep = new_ep;
                        X1_curr = X1fun(new_ep);
                        X2_curr = X2fun(new_ep);
                        X3_curr = X3fun(new_ep);
                    else
                        error("No solution found");
                    end
                        
                    if elastic
                        ep_max_vec = ones(Ncycles,1).*current_ep;
                        ep_min_vec = ones(Ncycles,1).*current_ep;
                        break;
                    end
                    
                    up = false;
                    ep_max_vec(cycle) = current_ep;
                    X1_max_vec(cycle) = X1_curr;
                    X2_max_vec(cycle) = X2_curr;
                    X3_max_vec(cycle) = X3_curr;
                else
                    if g1 ~= 0
                        X1fun = @(ep) -C1/g1 + (X1_curr+(C1/g1))*exp(-g1*abs(ep-current_ep));
                    else
                        X1fun = @(ep) X1_curr + C1*(ep-current_ep);
                    end
                    if g2 ~= 0
                        X2fun = @(ep) -C2/g2 + (X2_curr+(C2/g2))*exp(-g2*abs(ep-current_ep));
                    else
                        X2fun = @(ep) X2_curr + C2*(ep-current_ep);
                    end
                    if g3 ~= 0
                        X3fun = @(ep) -C3/g3 + (X3_curr+(C3/g3))*exp(-g3*abs(ep-current_ep));
                    else
                        X3fun = @(ep) X3_curr + C3*(ep-current_ep);
                    end
                    
                    tgt_fun = @(ep) X1fun(ep)+X2fun(ep)+X3fun(ep)-sigma0-sigma_min;
                    if tgt_fun(current_ep)*tgt_fun(-10)<0
                        new_ep = fzero(tgt_fun,[current_ep,-10]);
                        
                        current_ep = new_ep;
                        X1_curr = X1fun(new_ep);
                        X2_curr = X2fun(new_ep);
                        X3_curr = X3fun(new_ep);
                    else
                        error("No solution found");
                    end
                    
                    up = true;
                    ep_min_vec(cycle) = current_ep;
                    X1_min_vec(cycle) = X1_curr;
                    X2_min_vec(cycle) = X2_curr;
                    X3_min_vec(cycle) = X3_curr;
        
                    cycle = cycle + 1;
                end  
            end
            
            ep_max_vec = ep_max_vec(ep_max_vec~=0);
            ep_min_vec = ep_min_vec(ep_min_vec~=0);
            X1_max_vec = X1_max_vec(1:length(ep_max_vec));
            X1_min_vec = X1_min_vec(1:length(ep_min_vec));
            X2_max_vec = X2_max_vec(1:length(ep_max_vec));
            X2_min_vec = X2_min_vec(1:length(ep_min_vec));
            X3_max_vec = X3_max_vec(1:length(ep_max_vec));
            X3_min_vec = X3_min_vec(1:length(ep_min_vec));
            
            de_a_vec = (ep_max_vec(2:end)+ep_max_vec(1:end-1)-2*ep_min_vec(1:end-1))./2;
            de_r_vec = ep_max_vec(2:end)-ep_max_vec(1:end-1);
            de_s_vec = de_r_vec/2;
                    
            e_max_vec = ep_max_vec + sigma_max/E;
            e_min_vec = ep_min_vec + sigma_min/E;
            
            % Plot results
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.cycle_rat,app.max_e_rat,'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_3,'on')
            set(app.UIAxes_3,'XLim',[0 Ncycles])
            set(app.UIAxes_3,'YLim',[0 ep_thres])
            plot(app.UIAxes_3,e_max_vec,'-','Color',[0.64 0.08 0.18]);
            hold(app.UIAxes_3,'off')
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IdentifyParametersButton
        function IdentifyParametersButtonPushed(app, event)
            params = ParamIdentify(app);
            
            app.C1EditField.Value = params(3);
            app.g1EditField.Value = params(4);
            app.C2EditField.Value = params(5);
            app.g2EditField.Value = params(6);
            app.C3EditField.Value = params(2);
            app.X3_0EditField.Value = params(1);
            app.sigma0EditField.Value = params(7);
            
            StabSim(app,1)
            if ~isempty(app.sigma_stab_2)
                StabSim(app,2)
            end
            if ~isempty(app.cycle_rat)
                RatchSim(app)
            end
            
            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            [file, path] = uiputfile('*.mat');
            
            C1 = app.C1EditField.Value;
            g1 = app.g1EditField.Value;
            C2 = app.C2EditField.Value;
            g2 = app.g2EditField.Value;
            C3 = app.C3EditField.Value;
            s3 = app.X3_0EditField.Value;
            
            E = app.Eyoung;
            sigma0 = app.sigma0EditField.Value;
            
            save(fullfile(path,file),'C1','g1','C2','g2','C3','s3','sigma0','E');
            
            msgbox([file,' saved!'],'Info','help');
        end

        % Button pushed function: ImportDataButton_1
        function ImportDataButton_1Pushed(app, event)
            resetParam(app,1);
            
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'sigma_clean','e_clean','e_pl','cycle_clean','maxvec_s','minvec_s','cyclevec','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);

            app.CurrentFileEditField_1.Value = fullfile(path,file);
            
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end
            
            app.Eyoung = E;
            
            [app.sigma_stab_1,app.e_stab_1,app.e_pl_stab_1,app.delta_sigma_stab_1,app.sum_sigma_stab_1,app.delta_e_pl_stab_1,app.R_stab_1,app.area_stab_1,app.der_stab_1,app.sigmaY02_1] = StabExtractor(app,sigma_clean,e_clean,e_pl,cycle_clean,maxvec_s,minvec_s,cyclevec);
            
            cla(app.UIAxes_1)
            plot(app.UIAxes_1,[app.e_stab_1; app.e_stab_1(1)],[app.sigma_stab_1; app.sigma_stab_1(1)],'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_1,'on')
        end

        % Button pushed function: ImportDataButton_2
        function ImportDataButton_2Pushed(app, event)
            resetParam(app,2);
            
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'sigma_clean','e_clean','e_pl','cycle_clean','maxvec_s','minvec_s','cyclevec','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);
            
            app.CurrentFileEditField_2.Value = fullfile(path,file);
            
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end
            
            app.Eyoung = E;
            
            [app.sigma_stab_2,app.e_stab_2,app.e_pl_stab_2,app.delta_sigma_stab_2,app.sum_sigma_stab_2,app.delta_e_pl_stab_2,app.R_stab_2,app.area_stab_2,app.der_stab_2,app.sigmaY02_2] = StabExtractor(app,sigma_clean,e_clean,e_pl,cycle_clean,maxvec_s,minvec_s,cyclevec);
            
            cla(app.UIAxes_2)
            plot(app.UIAxes_2,[app.e_stab_2; app.e_stab_2(1)],[app.sigma_stab_2; app.sigma_stab_2(1)],'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_2,'on')
        end

        % Button pushed function: ImportDataButton_3
        function ImportDataButton_3Pushed(app, event)
            resetParam(app,3);
            
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'sigma_clean','e_clean','e_pl','cycle_clean','maxvec_e','minvec_e','maxvec_e_pl','minvec_e_pl','maxvec_s','minvec_s','cyclevec','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);
            
            app.CurrentFileEditField_3.Value = fullfile(path,file);
            
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end
            
            app.Eyoung = E;

            app.e_rat = e_clean;
            app.e_pl_rat = e_pl;
            
            lenmax = length(maxvec_e_pl);
            lenmin = length(minvec_e_pl);
            len = min([lenmin,lenmax]);
            
            maxvec_e_pl = maxvec_e_pl(1:len);
            minvec_e_pl = minvec_e_pl(1:len);
            app.delta_e_pl_av_rat = (maxvec_e_pl(1:end-1)+maxvec_e_pl(2:end)-2*minvec_e_pl(1:end-1))/2;
            app.delta_e_pl_s_rat = (maxvec_e_pl(2:end)+maxvec_e_pl(1:end-1))/2;
            
            app.cycle_rat = cyclevec(1:length(app.delta_e_pl_av_rat));
            app.max_e_rat = maxvec_e(1:length(app.cycle_rat));
            app.max_e_pl_rat = maxvec_e_pl(1:length(app.cycle_rat));
            
            app.max_sigma_rat = mean(maxvec_s);
            app.min_sigma_rat = mean(minvec_s);
            app.sigma_av_rat = (mean(maxvec_s)+mean(minvec_s))/2;
            
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.cycle_rat,app.max_e_rat,'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_3,'on')
        end

        % Button pushed function: ClearDataButton_1
        function ClearDataButton_1Pushed(app, event)
            resetParam(app,1);
            cla(app.UIAxes_1)
        end

        % Button pushed function: ClearDataButton_2
        function ClearDataButton_2Pushed(app, event)
            resetParam(app,2);
            cla(app.UIAxes_2)
        end

        % Button pushed function: ClearDataButton_3
        function ClearDataButton_3Pushed(app, event)
            resetParam(app,3);
            cla(app.UIAxes_3)
        end

        % Callback function
        function ClearDataButton_6Pushed(app, event)
            resetParam(app,6);
            cla(app.UIAxes_6)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChabocheParamFinder_3X_UIFigure = uifigure('Visible', 'off');
            app.ChabocheParamFinder_3X_UIFigure.Name = 'Chaboche Parameter Finder 3X';
            app.ChabocheParameterFinder3BackstressesRegularizedLSQLabel = uilabel(app.ChabocheParamFinder_3X_UIFigure);
            app.ChabocheParameterFinder3BackstressesRegularizedLSQLabel.Text = 'Chaboche Parameter Finder - 3 Backstresses - Direct Strategy';
            app.ExpCycle1Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle1Panel.Title = 'Experimental Cycle 1 (Bigger)';
            app.UIAxes_1 = uiaxes(app.ExpCycle1Panel);
            xlabel(app.UIAxes_1, '\epsilon')
            ylabel(app.UIAxes_1, '\sigma [MPa]')
            app.ImportDataButton_1 = uibutton(app.ExpCycle1Panel, 'push');
            app.ImportDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_1Pushed, true);
            app.ImportDataButton_1.Text = 'Import Data';
            app.CurrentFileEditFieldLabel = uilabel(app.ExpCycle1Panel);
            app.CurrentFileEditFieldLabel.Text = 'Current File';
            app.CurrentFileEditField_1 = uieditfield(app.ExpCycle1Panel, 'text');
            app.CurrentFileEditField_1.Editable = 'off';
            app.ClearDataButton_1 = uibutton(app.ExpCycle1Panel, 'push');
            app.ClearDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_1Pushed, true);
            app.ClearDataButton_1.Text = 'Clear Data';
            app.ExpCycle2Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle2Panel.Title = 'Experimental Cycle 2 (Smaller)';
            app.UIAxes_2 = uiaxes(app.ExpCycle2Panel);
            xlabel(app.UIAxes_2, '\epsilon')
            ylabel(app.UIAxes_2, '\sigma [MPa]')
            app.CurrentFileEditField_2Label = uilabel(app.ExpCycle2Panel);
            app.CurrentFileEditField_2Label.Text = 'Current File';
            app.CurrentFileEditField_2 = uieditfield(app.ExpCycle2Panel, 'text');
            app.CurrentFileEditField_2.Editable = 'off';
            app.ImportDataButton_2 = uibutton(app.ExpCycle2Panel, 'push');
            app.ImportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_2Pushed, true);
            app.ImportDataButton_2.Text = 'Import Data';
            app.ClearDataButton_2 = uibutton(app.ExpCycle2Panel, 'push');
            app.ClearDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_2Pushed, true);
            app.ClearDataButton_2.Text = 'Clear Data';
            app.ExpCycle3Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle3Panel.Title = 'Experimental Cycle 3 (Ratcheting)';
            app.UIAxes_3 = uiaxes(app.ExpCycle3Panel);
            xlabel(app.UIAxes_3, 'N')
            ylabel(app.UIAxes_3, '\epsilon_{max}')
            app.CurrentFileEditField_3Label = uilabel(app.ExpCycle3Panel);
            app.CurrentFileEditField_3Label.Text = 'Current File';
            app.CurrentFileEditField_3 = uieditfield(app.ExpCycle3Panel, 'text');
            app.CurrentFileEditField_3.Editable = 'off';
            app.ImportDataButton_3 = uibutton(app.ExpCycle3Panel, 'push');
            app.ImportDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_3Pushed, true);
            app.ImportDataButton_3.Text = 'Import Data';
            app.ClearDataButton_3 = uibutton(app.ExpCycle3Panel, 'push');
            app.ClearDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_3Pushed, true);
            app.ClearDataButton_3.Text = 'Clear Data';
            app.IdentifyParametersButton = uibutton(app.ChabocheParamFinder_3X_UIFigure, 'push');
            app.IdentifyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyParametersButtonPushed, true);
            app.IdentifyParametersButton.Text = 'Identify Parameters';
            app.ResultsPanel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.sigma0EditFieldLabel = uilabel(app.ResultsPanel);
            app.sigma0EditFieldLabel.Text = 'sigma0';
            app.sigma0EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.sigma0EditField.Editable = 'off';
            app.C1EditFieldLabel = uilabel(app.ResultsPanel);
            app.C1EditFieldLabel.Text = 'C1';
            app.C1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C1EditField.ValueDisplayFormat = '%.0f';
            app.C1EditField.Editable = 'off';
            app.g1EditFieldLabel = uilabel(app.ResultsPanel);
            app.g1EditFieldLabel.Text = 'g1';
            app.g1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.g1EditField.Editable = 'off';
            app.C3EditFieldLabel = uilabel(app.ResultsPanel);
            app.C3EditFieldLabel.Text = 'C3';
            app.C3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C3EditField.Editable = 'off';
            app.X3_0EditFieldLabel = uilabel(app.ResultsPanel);
            app.X3_0EditFieldLabel.Text = 'X3_0';
            app.X3_0EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.X3_0EditField.Editable = 'off';
            app.C2EditFieldLabel = uilabel(app.ResultsPanel);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C2EditField.ValueDisplayFormat = '%.0f';
            app.C2EditField.Editable = 'off';
            app.g2EditFieldLabel = uilabel(app.ResultsPanel);
            app.g2EditFieldLabel.Text = 'g2';
            app.g2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.g2EditField.Editable = 'off';
            app.SaveResultsButton = uibutton(app.ChabocheParamFinder_3X_UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            % Show the figure after all components are created
            app.ChabocheParamFinder_3X_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChabocheParamFinder_3X_Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChabocheParamFinder_3X_UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChabocheParamFinder_3X_UIFigure)
        end
    end
end