classdef ChaPaFi_v1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ChabocheParamFinder_3X_UIFigure  matlab.ui.Figure
        SaveResultsButton            matlab.ui.control.Button
        ResultsPanel                 matlab.ui.container.Panel
        g2EditField                  matlab.ui.control.NumericEditField
        g2EditFieldLabel             matlab.ui.control.Label
        C2EditField                  matlab.ui.control.NumericEditField
        C2EditFieldLabel             matlab.ui.control.Label
        X3_0EditField                matlab.ui.control.NumericEditField
        X3_0EditFieldLabel           matlab.ui.control.Label
        C3EditField                  matlab.ui.control.NumericEditField
        C3EditFieldLabel             matlab.ui.control.Label
        g1EditField                  matlab.ui.control.NumericEditField
        g1EditFieldLabel             matlab.ui.control.Label
        C1EditField                  matlab.ui.control.NumericEditField
        C_1Label                     matlab.ui.control.Label
        sigma0EditField              matlab.ui.control.NumericEditField
        sigma0EditFieldLabel         matlab.ui.control.Label
        IdentifyParametersButton     matlab.ui.control.Button
        ExpCycle3Panel               matlab.ui.container.Panel
        ClearDataButton_3            matlab.ui.control.Button
        ImportDataButton_3           matlab.ui.control.Button
        CurrentFileEditField_3       matlab.ui.control.EditField
        CurrentFileEditField_3Label  matlab.ui.control.Label
        UIAxes_3                     matlab.ui.control.UIAxes
        ExpCycle2Panel               matlab.ui.container.Panel
        ClearDataButton_2            matlab.ui.control.Button
        ImportDataButton_2           matlab.ui.control.Button
        CurrentFileEditField_2       matlab.ui.control.EditField
        CurrentFileEditField_2Label  matlab.ui.control.Label
        UIAxes_2                     matlab.ui.control.UIAxes
        ExpCycle1Panel               matlab.ui.container.Panel
        ClearDataButton_1            matlab.ui.control.Button
        CurrentFileEditField_1       matlab.ui.control.EditField
        CurrentFileEditFieldLabel    matlab.ui.control.Label
        ImportDataButton_1           matlab.ui.control.Button
        UIAxes_1                     matlab.ui.control.UIAxes
        ChabocheParameterFinder3BackstressesRegularizedLSQLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        sigma_sim_1 = []; % Simulated Stabilized Cycle (Sigma)
        e_sim_1 = []; % Simulated Stabilized Cycle (Epsilon)
        sigma_stab_1 = []; % Imported Stabilized Cycle (Sigma)
        e_stab_1 = []; % Imported Stabilized Cycle (Epsilon)
        e_pl_stab_1 = []; % Imported Stabilized Cycle (Plastic Epsilon)
        delta_e_pl_stab_1 = []; % Imported Delta Plastic Epsilon
        R_stab_1 = []; % Imported R
        sum_sigma_stab_1 = []; % Imported Sum of Stress Extreme Values
        delta_sigma_stab_1 = []; % Imported Delta of Stress Extreme Values
        area_stab_1 = []; % Imported Plastic Work
        der_stab_1 = []; % Imported Slope at Cycle Extremities
        sigmaY02_1 = []; % Imported Yield-Strength at 0.2%
        
        sigma_sim_2 = []; % Simulated Stabilized Cycle (Sigma)
        e_sim_2 = []; % Simulated Stabilized Cycle (Epsilon)
        sigma_stab_2 = []; % Imported Stabilized Cycle (Sigma)
        e_stab_2 = []; % Imported Stabilized Cycle (Epsilon)
        e_pl_stab_2 = []; % Imported Stabilized Cycle (Plastic Epsilon)
        delta_e_pl_stab_2 = []; % Imported Delta Plastic Epsilon
        R_stab_2 = []; % Imported R
        sum_sigma_stab_2 = []; % Imported Sum of Stress Extreme Values
        delta_sigma_stab_2 = []; % Imported Delta of Stress Extreme Values
        area_stab_2 = []; % Imported Plastic Work
        der_stab_2 = []; % Imported Slope at Cycle Extremities
        sigmaY02_2 = []; % Imported Yield-Strength at 0.2%
        
        e_sim_rat = []; % Simulated Ratcheting (Max Epsilon)
        sigma_rat = []; % Imported Ratcheting (Sigma)
        e_rat = []; % Imported Ratcheting (Epsilon)
        e_pl_rat = []; % Imported Ratcheting (Plastic Epsilon)
        cycle_rat = []; % Imported Cycle Number
        max_sigma_rat = []; % Imported Max Sigma
        min_sigma_rat = []; % Imported Min Sigma
        max_e_rat = [], % Imported Max Epsilon
        max_e_pl_rat = [], % Imported Max Epsilon
        delta_e_pl_av_rat = []; % Imported Delta Average Plastic Epsilon
        delta_e_pl_s_rat = []; % Imported Delta Shift Plastic Epsilon
        sigma_av_rat = []; % Imported Mean Stress

        Eyoung = []; % Young Modulus

        gamma_trial = 10:2000; % Range of gamma1 on which the error function is minimized
        condlimitA = 500; % Maximum condition number of matrix A for identification of C3 and X3_0
    end

    methods (Access = private)
    
        % Simulation of stabilized cycles in strain control
        function StabSim(app,WinNum)
            npoints = 100;
            
            % Get Values
            C1 = app.C1EditField.Value;   % C1
            g1 = app.g1EditField.Value;   % g1
            s1 = 0;                       % Starting value of X1
            C2 = app.C2EditField.Value;   % C2
            g2 = app.g2EditField.Value;   % g2
            s2 = 0;                       % Starting value of X2
            C3 = app.C3EditField.Value;   % C3
            g3 = 0;                       % g3
            s3 = app.X3_0EditField.Value; % Starting value of X3
            
            E = app.Eyoung; % Young Modulus
            sigma0 = app.sigma0EditField.Value; % Sigma0
            
            % Get strain inputs
            switch WinNum
                case 1
                    delta_ep = app.delta_e_pl_stab_1;
                    R = app.R_stab_1;
                case 2
                    delta_ep = app.delta_e_pl_stab_2;
                    R = app.R_stab_2;
            end
            max_ep = delta_ep/(1-R);
            min_ep = max_ep-delta_ep;
                    
            % Create strain ramps
            ep_up = linspace(min_ep,max_ep,npoints);
            ep_down = linspace(max_ep,min_ep,npoints);
            
            % Get extremal backstresses and integrate (1)
            if g1 == 0
                X1m = C1*min_ep+s1;
                X1M = C1*max_ep+s1;
                
                X1_up = s1+C1.*ep_up;
                X1_down = s1+C1.*ep_down;
            else
                X1m = -(C1/g1)*(1-exp(-g1*delta_ep))/(1+exp(-g1*delta_ep));
                X1M = -X1m;
                
                X1_up = (C1/g1) + (X1m - C1/g1)*exp(-g1*abs(ep_up-min_ep));
                X1_down = -(C1/g1) + (X1M + C1/g1)*exp(-g1*abs(ep_down-max_ep));
            end
            
            % Get extremal backstresses and integrate (2)
            if g2 == 0
                X2m = C2*min_ep+s2;
                X2M = C2*max_ep+s2;
                
                X2_up = s2+C2.*ep_up;
                X2_down = s2+C2.*ep_down;
            else
                X2m = -(C2/g2)*(1-exp(-g2*delta_ep))/(1+exp(-g2*delta_ep));
                X2M = -X2m;
                
                X2_up = (C2/g2) + (X2m - C2/g2)*exp(-g2*abs(ep_up-min_ep));
                X2_down = -(C2/g2) + (X2M + C2/g2)*exp(-g2*abs(ep_down-max_ep));
            end
            
            % Get extremal backstresses and integrate (3)
            if g3 == 0
                X3m = C3*min_ep+s3;
                X3M = C3*max_ep+s3;
                
                X3_up = s3+C3.*ep_up;
                X3_down = s3+C3.*ep_down;
            else
                X3m = -(C3/g3)*(1-exp(-g3*delta_ep))/(1+exp(-g3*delta_ep));
                X3M = -X3m;
                
                X3_up = (C3/g3) + (X3m - C3/g3)*exp(-g3*abs(ep_up-min_ep));
                X3_down = -(C3/g3) + (X3M + C3/g3)*exp(-g3*abs(ep_down-max_ep));
            end
            
            % Build stresses
            sigma_start = -sigma0 + X1m + X2m + X3m;
            sigma_up = sigma0 + X1_up + X2_up + X3_up;
            sigma_down = -sigma0 + X1_down + X2_down + X3_down;
            
            % Build strains
            e_start = min_ep + sigma_start/E;
            e_up = ep_up + sigma_up/E;
            e_down = ep_down + sigma_down/E;

            % Store variables and Plot results
            switch WinNum
                case 1
                    app.sigma_sim_1 = [sigma_start, sigma_up, sigma_down];
                    app.e_sim_1 = [e_start, e_up, e_down];
                    
                    cla(app.UIAxes_1)
                    plot(app.UIAxes_1,[app.e_stab_1; app.e_stab_1(1)],[app.sigma_stab_1; app.sigma_stab_1(1)],'Color',[0 0.45 0.74],'LineWidth',2)
                    hold(app.UIAxes_1,'on')
                    plot(app.UIAxes_1,app.e_sim_1,app.sigma_sim_1,'Color',[0.64 0.08 0.18],'LineWidth',2);
                    hold(app.UIAxes_1,'off')
                case 2
                    app.sigma_sim_2 = [sigma_start, sigma_up, sigma_down];
                    app.e_sim_2 = [e_start, e_up, e_down];
                    
                    cla(app.UIAxes_2)
                    plot(app.UIAxes_2,[app.e_stab_2; app.e_stab_2(1)],[app.sigma_stab_2; app.sigma_stab_2(1)],'Color',[0 0.45 0.74],'LineWidth',2)
                    hold(app.UIAxes_2,'on')
                    plot(app.UIAxes_2,app.e_sim_2,app.sigma_sim_2,'Color',[0.64 0.08 0.18],'LineWidth',2);
                    hold(app.UIAxes_2,'off')
            end
        end

        function [delta_sigma_stab,sum_sigma_stab,delta_e_pl_stab,R_stab,area_stab,der_stab,sigmaY02] = StabExtractor(app,sigma_stab,e_stab,e_pl_stab)
            
            % Postprocessing of stabilized cycle
            max_sigma_stab = max(sigma_stab);
            min_sigma_stab = min(sigma_stab);
            max_e_pl_stab = max(e_pl_stab);
            min_e_pl_stab = min(e_pl_stab);
            delta_sigma_stab = max_sigma_stab - min_sigma_stab;
            sum_sigma_stab = max_sigma_stab + min_sigma_stab;
            delta_e_pl_stab = max_e_pl_stab - min_e_pl_stab;
            R_stab = min_e_pl_stab/max_e_pl_stab;
            
            % Zero-crossing anonymous function
            zci = @(v) find(diff(sign(v)));
            
            % 0.2% Yield Stress
            sigmaY02up = max(sigma_stab(zci(e_pl_stab-min_e_pl_stab-0.002)))-min_sigma_stab;
            sigmaY02down = max_sigma_stab-min(sigma_stab(zci(max_e_pl_stab-e_pl_stab-0.002)));            
            sigmaY02 = (sigmaY02up+sigmaY02down)/2;
            
            % Area of stabilized cycle
            area_stab = polyarea(e_pl_stab,sigma_stab);
            
            if sigma_stab(2)-sigma_stab(1) > 0 % First ramp is ascending
                ind_tgt = find(sigma_stab==max_sigma_stab,1);
            else
                ind_tgt = find(sigma_stab==min_sigma_stab,1);
            end
            
            % Extract ramp
            sigma_2der = sigma_stab(1:ind_tgt);
            e_2der = e_stab(1:ind_tgt);

            % Fit a line to the last 10 points of the ramp, and take its first coefficient 
            p = polyfit(e_2der(end-11:end-1),sigma_2der(end-11:end-1),1);   
            
            % Derivative at extreme point, with respect to total strains
            der_stab_tot = p(1);

            % Derivative at extreme point, with respect to plastic strains
            der_stab = app.Eyoung*der_stab_tot./(app.Eyoung-der_stab_tot);
        end
        
        % Chaboche parameters identification
        function params = ParamIdentify(app)
            delta_e_pl_stab_vec = [app.delta_e_pl_stab_1; app.delta_e_pl_stab_2];
            delta_sigma_stab_vec = [app.delta_sigma_stab_1; app.delta_sigma_stab_2];
            sum_sigma_stab_vec = [app.sum_sigma_stab_1; app.sum_sigma_stab_2];
            area_stab_vec = [app.area_stab_1; app.area_stab_2];
            der_stab_vec = [app.der_stab_1; app.der_stab_2];
            R_stab_vec = [app.R_stab_1; app.R_stab_2];
            
            params_mat = zeros(6,length(app.gamma_trial)); % Matrix of identified parameters
            sigma0trial = zeros(length(app.gamma_trial),1); % Vector of identified sigma0
            
            nEq = length(R_stab_vec);
            
            % Identification can be carried out if both stabilized cycles are available
            if nEq == 2
                A = zeros(nEq,2);
                
                % Linear system for the identification of C3 and X3_0
                A(:,1) = 2;
                A(:,2) = delta_e_pl_stab_vec.*(1+R_stab_vec)./(1-R_stab_vec);
                b = sum_sigma_stab_vec;
                
                % Solve it only if it is decently conditioned
                if cond(A) < app.condlimitA
                    params_mat(1:2,:) = repmat(pinv(A)*b,1,size(params_mat,2));
                else
                    params_mat(2,:) = 0;
                    A = A(:,1);
                    params_mat(1,:) = repmat(pinv(A)*b,1,size(params_mat,2));
                end

                % Evaluate auxiliary variables for every trial value of g1
                for i=1:length(app.gamma_trial)
                    k1 = der_stab_vec(1)-params_mat(2,i);
                    k2 = der_stab_vec(2)-params_mat(2,i);
                    
                    a1 = 1-tanh(app.gamma_trial(i)*delta_e_pl_stab_vec(1)/2);
                    a2 = 1-tanh(app.gamma_trial(i)*delta_e_pl_stab_vec(2)/2);

                    params_mat(4,i) = app.gamma_trial(i);
                    params_mat(6,i) = 1e-3;
                    
                    params_mat(3,i) = (k1-k2)/(a1-a2);
                    params_mat(5,i) = (a1*k2-a2*k1)/(a1-a2);
                    
                    sigma0_1 = (delta_sigma_stab_vec(1)-((2*params_mat(3,i)/params_mat(4,i))*tanh(params_mat(4,i)*delta_e_pl_stab_vec(1)/2) + (params_mat(5,i)+params_mat(2,i))*delta_e_pl_stab_vec(1)))/2;
                    sigma0_2 = (delta_sigma_stab_vec(2)-((2*params_mat(3,i)/params_mat(4,i))*tanh(params_mat(4,i)*delta_e_pl_stab_vec(2)/2) + (params_mat(5,i)+params_mat(2,i))*delta_e_pl_stab_vec(2)))/2;
   
                    sigma0trial(i) = (sigma0_1+sigma0_2)/2;
                end
            else
                % Error message
                msg = msgbox({'At least two stabilized cycles are needed!'},'Error','error');
                msgboxFontSize(msg,12);
                drawnow;
            end
            
            % Initialize error function values
            SqErr = zeros(size(app.gamma_trial));
            
            % Compute error function
            for i=1:length(app.gamma_trial)
                % Compute values only if C1, C2, g2 and sigma0 are strictly positive
                if all(params_mat(3:6,i)>=0) && sigma0trial(i)>=0
                    SqErr(i) = error_fun(app,[params_mat(3:6,i);sigma0trial(i)],params_mat(2,i),delta_e_pl_stab_vec,delta_sigma_stab_vec,area_stab_vec);
                else
                    % ...otherwise set error to +Inf
                    SqErr(i) = +Inf;
                end
                
            end
            
            % Find minimum of error function
            [minerr,inderr] = min(SqErr);

            % Store corresponding parameters
            params = [params_mat(:,inderr);sigma0trial(inderr)];

            % If the ratcheting cycle is available, use it to tune g2
            if ~isempty(app.cycle_rat)
                len = length(app.max_e_pl_rat);
                lend = len-1;
                
                A = eye(lend);
                c = Tik2(app,lend); % Matrix for second-order Tikhonov filtering

                alfa = 1000; % A high value, for a substantial low-pass filter
                dmaxvec_e_pl_filt = pinv((A'*A) + alfa*(c'*c))*A'*diff(app.max_e_pl_rat)'; % Filtered slope
                
                zci = @(v) find(diff(sign(v))); % Zero-crossing function
                zc = zci(diff(dmaxvec_e_pl_filt)); % Find stationary point of slope
                indminslope = zc(end); % Minimum slope is generally attained just before the final Phase 3
                indtgt = floor(indminslope*0.9); % Take a point which is slightly before the boundary between Phase 2 and 3
                
                % Compute corresponding quantities
                delta_e_pl_r = dmaxvec_e_pl_filt(indtgt);
                delta_e_pl_s = delta_e_pl_r/2;
                delta_e_pl_av = app.delta_e_pl_av_rat(indtgt);
                
                % Adjust value of g2
                params(6) = asinh(((app.sigma_av_rat/delta_e_pl_s - params(3)/sinh(params(4)*delta_e_pl_av))^-1)*params(5))/delta_e_pl_av;
                
                % Recompute the value of the error function
                minerr = error_fun(app,params(3:end),params(2),delta_e_pl_stab_vec,delta_sigma_stab_vec,area_stab_vec);
            else
                % Otherwise, set g2 to 1
                params(6) = 1;
            end
            
            % Display final value of the error function
%             disp('Minimum Cumulative RMS error = ')
%             disp(sqrt(minerr))
            
        end
        
        % Reset parameters
        function resetParam(app,WinNum)
            app.sigma_sim_1 = [];
            app.e_sim_1 = [];
            app.sigma_sim_2 = [];
            app.e_sim_2 = [];
            
            switch WinNum
                case 1
                    app.sigma_stab_1 = [];
                    app.e_stab_1 = [];
                    app.delta_e_pl_stab_1 = [];
                    app.R_stab_1 = [];
                    app.sum_sigma_stab_1 = [];
                    app.delta_sigma_stab_1 = [];
                    app.area_stab_1 = [];
                    app.der_stab_1 = [];
                    app.sigmaY02_1 = [];
                    app.CurrentFileEditField_1.Value = '';
                case 2
                    app.sigma_stab_2 = [];
                    app.e_stab_2 = [];
                    app.delta_e_pl_stab_2 = [];
                    app.R_stab_2 = [];
                    app.sum_sigma_stab_2 = [];
                    app.delta_sigma_stab_2 = [];
                    app.area_stab_2 = [];
                    app.der_stab_2 = [];
                    app.sigmaY02_2 = [];
                    app.CurrentFileEditField_2.Value = '';
                case 3
                    app.e_sim_rat = [];
                    app.sigma_rat = [];
                    app.e_rat = [];
                    app.e_pl_rat = [];
                    app.cycle_rat = [];
                    app.max_sigma_rat = [];
                    app.min_sigma_rat = [];
                    app.max_e_rat = [];
                    app.max_e_pl_rat = [];
                    app.delta_e_pl_av_rat = [];
                    app.delta_e_pl_s_rat = [];
                    app.sigma_av_rat = [];
                    app.CurrentFileEditField_3.Value = '';
            end
            
            app.C1EditField.Value = 0;
            app.g1EditField.Value = 0;
            app.C2EditField.Value = 0;
            app.g2EditField.Value = 0;
            app.C3EditField.Value = 0;
            app.X3_0EditField.Value = 0;
            app.sigma0EditField.Value = 0;
        end
        
        % Simulation of ratcheting curves in stress control
        function RatchSim(app)

            Ncycles = 100; % Number of ratcheting cycles to simulate
            ep_step = 1e-5; % Strain simulation step
            
            current_ep = 0; % Initial strain
            ep_thres = 0.10; % Maximum simulated strain
            cycle = 1; % Cycle initialization
            
            % Get Values
            C1 = app.C1EditField.Value;   % C1
            g1 = app.g1EditField.Value;   % g1
            X1 = 0;                       % Starting value of X1
            C2 = app.C2EditField.Value;   % C2
            g2 = app.g2EditField.Value;   % g2
            X2 = 0;                       % Starting value of X2
            C3 = app.C3EditField.Value;   % C3
            g3 = 0;                       % g3
            X3 = app.X3_0EditField.Value; % Starting value of X3
            
            E = app.Eyoung; % Young Modulus
            sigma0 = app.sigma0EditField.Value; % Sigma0
            
            % Get stress inputs
            sigma_max = app.max_sigma_rat;
            sigma_min = app.min_sigma_rat;
            
            % Check whether the stress input can be covered within the
            % material elastic domain
            elastic = false;
            if sigma_max-sigma_min < 2*sigma0
                elastic = true;
            end

            % Result initialization
            ep_max_vec = zeros(Ncycles,1);
            ep_min_vec = zeros(Ncycles,1);
            X1_max_vec = zeros(Ncycles,1);
            X1_min_vec = zeros(Ncycles,1);
            X2_max_vec = zeros(Ncycles,1);
            X2_min_vec = zeros(Ncycles,1);
            X3_max_vec = zeros(Ncycles,1);
            X3_min_vec = zeros(Ncycles,1);
            
            % Current backstresses
            X1_curr = X1;
            X2_curr = X2;
            X3_curr = X3;
            
            % Initial direction
            up = true;
            
            % Ratcheting cycle simulation
            while cycle < Ncycles

                % Break condition
                if current_ep>ep_thres
                    break;
                end

                % Ascending ramp
                if up  
                    % Backstress laws
                    if g1 ~= 0
                        X1fun = @(ep) C1/g1 + (X1_curr-(C1/g1))*exp(-g1*abs(ep-current_ep));
                    else
                        X1fun = @(ep) X1_curr + C1*(ep-current_ep);
                    end
                    if g2 ~= 0
                        X2fun = @(ep) C2/g2 + (X2_curr-(C2/g2))*exp(-g2*abs(ep-current_ep));
                    else
                        X2fun = @(ep) X2_curr + C2*(ep-current_ep);
                    end
                    if g3 ~= 0
                        X3fun = @(ep) C3/g3 + (X3_curr-(C3/g3))*exp(-g3*abs(ep-current_ep));
                    else
                        X3fun = @(ep) X3_curr + C3*(ep-current_ep);
                    end
                    
                    % If the cycle is elastic, no ratcheting occurs
                    if elastic
                        ep_max_vec = ones(Ncycles,1).*current_ep;
                        ep_min_vec = ones(Ncycles,1).*current_ep;
                        break;
                    end

                    % Find strain at ramp end
                    tgt_fun = @(ep) X1fun(ep)+X2fun(ep)+X3fun(ep)+sigma0-sigma_max;
                    if tgt_fun(current_ep)*tgt_fun(10)<0
                        new_ep = fzero(tgt_fun,[current_ep,10]);
                        
                        % Update current strain
                        current_ep = new_ep;

                        % Update current backstresses
                        X1_curr = X1fun(new_ep);
                        X2_curr = X2fun(new_ep);
                        X3_curr = X3fun(new_ep);
                    else
                        error("No solution found");
                    end
                    
                    % Next ramp will be a descending one
                    up = false;

                    % Store results
                    ep_max_vec(cycle) = current_ep;
                    X1_max_vec(cycle) = X1_curr;
                    X2_max_vec(cycle) = X2_curr;
                    X3_max_vec(cycle) = X3_curr;
                else
                    % Backstress laws
                    if g1 ~= 0
                        X1fun = @(ep) -C1/g1 + (X1_curr+(C1/g1))*exp(-g1*abs(ep-current_ep));
                    else
                        X1fun = @(ep) X1_curr + C1*(ep-current_ep);
                    end
                    if g2 ~= 0
                        X2fun = @(ep) -C2/g2 + (X2_curr+(C2/g2))*exp(-g2*abs(ep-current_ep));
                    else
                        X2fun = @(ep) X2_curr + C2*(ep-current_ep);
                    end
                    if g3 ~= 0
                        X3fun = @(ep) -C3/g3 + (X3_curr+(C3/g3))*exp(-g3*abs(ep-current_ep));
                    else
                        X3fun = @(ep) X3_curr + C3*(ep-current_ep);
                    end
                    
                    % Find strain at ramp end
                    tgt_fun = @(ep) X1fun(ep)+X2fun(ep)+X3fun(ep)-sigma0-sigma_min;
                    if tgt_fun(current_ep)*tgt_fun(-10)<0
                        new_ep = fzero(tgt_fun,[current_ep,-10]);
                        
                        % Update current strain
                        current_ep = new_ep;

                        % Store results
                        X1_curr = X1fun(new_ep);
                        X2_curr = X2fun(new_ep);
                        X3_curr = X3fun(new_ep);
                    else
                        error("No solution found");
                    end
                    
                    % Next ramp will be an ascending one
                    up = true;

                    % Store results
                    ep_min_vec(cycle) = current_ep;
                    X1_min_vec(cycle) = X1_curr;
                    X2_min_vec(cycle) = X2_curr;
                    X3_min_vec(cycle) = X3_curr;
        
                    % One cycle = 1 ascending + 1 descending ramp
                    cycle = cycle + 1;
                end  
            end
            
            % Update result length
            ep_max_vec = ep_max_vec(ep_max_vec~=0);
            ep_min_vec = ep_min_vec(ep_min_vec~=0);
            X1_max_vec = X1_max_vec(1:length(ep_max_vec));
            X1_min_vec = X1_min_vec(1:length(ep_min_vec));
            X2_max_vec = X2_max_vec(1:length(ep_max_vec));
            X2_min_vec = X2_min_vec(1:length(ep_min_vec));
            X3_max_vec = X3_max_vec(1:length(ep_max_vec));
            X3_min_vec = X3_min_vec(1:length(ep_min_vec));
            
            % Calculate auxiliary results
            de_a_vec = (ep_max_vec(2:end)+ep_max_vec(1:end-1)-2*ep_min_vec(1:end-1))./2;
            de_r_vec = ep_max_vec(2:end)-ep_max_vec(1:end-1);
            de_s_vec = de_r_vec/2;
                    
            % Evaluate elastic strains
            e_max_vec = ep_max_vec + sigma_max/E;
            e_min_vec = ep_min_vec + sigma_min/E;
            
            % Plot results
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.cycle_rat,app.max_e_rat,'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_3,'on')
            set(app.UIAxes_3,'XLim',[0 Ncycles])
            set(app.UIAxes_3,'YLim',[0 max(app.max_e_rat)])
%             set(app.UIAxes_3,'YLim',[0 max(e_max_vec)])
%             set(app.UIAxes_3,'YLim',[0 0.01])
            plot(app.UIAxes_3,e_max_vec,'-','Color',[0.64 0.08 0.18]);
            hold(app.UIAxes_3,'off')
            
        end
        
        function [sigma_clean,e_clean,e_pl,maxvec_e,minvec_e,maxvec_e_pl,minvec_e_pl,maxvec_s,minvec_s] = RatCleanerFun(app,sigma,e)

            % Initialize
            sigma_clean = sigma;
            e_clean = e;
            
            max_sigma = max(sigma);
            temp = find(sigma>0.9*max_sigma); % Etract maximums
            intrvl = max(diff(temp)); % Estimate number of samples between maximums
            if isempty(intrvl) || intrvl == 1 % A single cycle has been supplied
                intrvl = length(sigma);
                [maxvec_s,indmax] = max(sigma);
                [minvec_s,indmin] = min(sigma);
                maxvec_e = e(indmax);
                minvec_e = e(indmin);
                disp('Single cycle supplied');
            end
            
            i = 1;
            j = 1;
            zM = 1;
            zm = 1;
            up = true; % First cycle is tensile
            while i<length(sigma)
                if up
                    next = find(sigma(i+1:end)>sigma(i),1); % Look for next sample
                else
                    next = find(sigma(i+1:end)<sigma(i),1); % Look for next sample
                end
                if next<intrvl/2 % If next value is "near", we are on the same ramp
                    e_clean(j+1) = e(i+next);
                    sigma_clean(j+1) = sigma(i+next);
                    j = j+1;
                    i = i+next;
                else % Otherwise, we are at the peak
                    e_clean(j+1) = e(i+1);
                    sigma_clean(j+1) = sigma(i+1);
                    if up
                        % Store maximum values
                        maxvec_s(zM) = sigma(i);
                        maxvec_e(zM) = e(i);
                        zM = zM+1;
                    else
                        % Store minimum values
                        minvec_s(zm) = sigma(i);
                        minvec_e(zm) = e(i);
                        zm = zm+1;
                    end
                    j = j+1;
                    i = i+1;

                    % Invert direction
                    up = ~up;
                end
            end
            
            % Trim arrays
            sigma_clean = sigma_clean(1:j);
            e_clean = e_clean(1:j);
            
            % Compute plastic strains
            e_pl = e_clean - sigma_clean./app.Eyoung;
            maxvec_e_pl = maxvec_e - maxvec_s./app.Eyoung;
            minvec_e_pl = minvec_e - minvec_s./app.Eyoung;
        
        end
        
        function err = error_fun(app,x,C3,delta_e_pl_stab_vec,delta_sigma_stab_vec,area_stab_vec)

            % x(1) = C1
            % x(2) = g1
            % x(3) = C2
            % x(4) = g2
            % x(5) = sigma0

            % ALPHA PARAMETER
            alpha = 0.5;
            
            nCyc = length(delta_e_pl_stab_vec);
            
            % Vector of "true" sigmaL
            sigma_Lcyc = delta_sigma_stab_vec./2 - (x(1)./x(2)).*(1-exp(-x(2).*delta_e_pl_stab_vec))./(1+exp(-x(2).*delta_e_pl_stab_vec))...
                - (x(3)./x(4)).*(1-exp(-x(4).*delta_e_pl_stab_vec))./(1+exp(-x(4).*delta_e_pl_stab_vec))...
                - (C3/2).*delta_e_pl_stab_vec;
            
            % Average sigmaL
            sigma_L = mean(sigma_Lcyc);
            
            % Relative error on peak values
            peak_err = ((max(sigma_Lcyc)-min(sigma_Lcyc))/sigma_L).^2;
            
            % Relative error on hysteresis areas
            hist_err = sum(((2.*(x(1)./x(2)).*delta_e_pl_stab_vec - 4.*(x(1)./(x(2).^2)).*(1-exp(-x(2).*delta_e_pl_stab_vec))./(1+exp(-x(2).*delta_e_pl_stab_vec))...
                + 2.*(x(3)./x(4)).*delta_e_pl_stab_vec - 4.*(x(3)./(x(4).^2)).*(1-exp(-x(4).*delta_e_pl_stab_vec))./(1+exp(-x(4).*delta_e_pl_stab_vec))...
                + 2.*x(5).*delta_e_pl_stab_vec - area_stab_vec)./(1.*area_stab_vec)).^2);
            
            % Global error function
            err = (1-alpha).*peak_err + alpha.*hist_err;
        end
        
        function C = Tik2(app,n)
            % Compute second-order discrete derivative matrix, 
            % for Tikhonov regularization
            
            % Compute C.
            D = [-1,2,-1];
            m = n-2;
            C = zeros(m,n);
            for i=1:m
              C(i,i:i+2)=D;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IdentifyParametersButton
        function IdentifyParametersButtonPushed(app, event)
            % Run procedure
            params = ParamIdentify(app);
            
            % Assign parameters to GUI fields
            app.C1EditField.Value = params(3);
            app.g1EditField.Value = params(4);
            app.C2EditField.Value = params(5);
            app.g2EditField.Value = params(6);
            app.C3EditField.Value = params(2);
            app.X3_0EditField.Value = params(1);
            app.sigma0EditField.Value = params(7);
            
            % Plot stabilized cycles (Exp. and Sim.)
            StabSim(app,1)
            StabSim(app,2)

            % Plot ratcheting cycles, if present (Exp. and Sim.)
            if ~isempty(app.cycle_rat)
                RatchSim(app)
            end            
        end

        % Button pushed function: SaveResultsButton
        function SaveResultsButtonPushed(app, event)
            % Choose file name
            [file, path] = uiputfile('*.mat');
            
            % Store temporary variables
            C1 = app.C1EditField.Value;
            g1 = app.g1EditField.Value;
            C2 = app.C2EditField.Value;
            g2 = app.g2EditField.Value;
            C3 = app.C3EditField.Value;
            s3 = app.X3_0EditField.Value;          
            E = app.Eyoung;
            sigma0 = app.sigma0EditField.Value;
            
            % Save variables
            save(fullfile(path,file),'C1','g1','C2','g2','C3','s3','sigma0','E');
            
            msgbox([file,' saved!'],'Info','help');
        end

        % Button pushed function: ImportDataButton_1
        function ImportDataButton_1Pushed(app, event)
            % Reset parameters, as new data is being imported
            resetParam(app,1);
            
            % Load file variables
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'s','e','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);

            % Show file path
            app.CurrentFileEditField_1.Value = fullfile(path,file);
            
            % Send warning if a different Young Modulus is detected
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end
            
            % Calculate plastic strains
            e_pl = e-s/E;

            % Store variables
            app.Eyoung = E;
            app.sigma_stab_1 = s;
            app.e_stab_1 = e;
            app.e_pl_stab_1 = e_pl;

            % Post-process stabilized cycle
            [app.delta_sigma_stab_1,app.sum_sigma_stab_1,app.delta_e_pl_stab_1,app.R_stab_1,app.area_stab_1,app.der_stab_1,app.sigmaY02_1] = StabExtractor(app,s,e,e_pl);
            
            % Plot data
            cla(app.UIAxes_1)
            plot(app.UIAxes_1,[app.e_stab_1; app.e_stab_1(1)],[app.sigma_stab_1; app.sigma_stab_1(1)],'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_1,'on')
        end

        % Button pushed function: ImportDataButton_2
        function ImportDataButton_2Pushed(app, event)
            % Reset parameters, as new data is being imported
            resetParam(app,2);
            
            % Load file variables
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'s','e','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);
            
            % Show file path
            app.CurrentFileEditField_2.Value = fullfile(path,file);
            
            % Send warning if a different Young Modulus is detected
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end

            % Calculate plastic strains
            e_pl = e-s/E;

            % Store variables
            app.Eyoung = E;
            app.sigma_stab_2 = s;
            app.e_stab_2 = e;
            app.e_pl_stab_2 = e_pl;
            
            % Post-process stabilized cycle
            [app.delta_sigma_stab_2,app.sum_sigma_stab_2,app.delta_e_pl_stab_2,app.R_stab_2,app.area_stab_2,app.der_stab_2,app.sigmaY02_2] = StabExtractor(app,s,e,e_pl);
            
            % Plot data
            cla(app.UIAxes_2)
            plot(app.UIAxes_2,[app.e_stab_2; app.e_stab_2(1)],[app.sigma_stab_2; app.sigma_stab_2(1)],'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_2,'on')
        end

        % Button pushed function: ImportDataButton_3
        function ImportDataButton_3Pushed(app, event)
            % Reset parameters, as new data is being imported
            resetParam(app,3);
            
            % Load file variables
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'off'; 
            [file,path] = uigetfile('*.mat');
            Vars = {'s','e','E'};
            load(fullfile(path,file),Vars{:});
%             app.ChabocheParamFinder_3X_UIFigure.Visible = 'on'; % Correction to a MATLAB known bug, see https://it.mathworks.com/matlabcentral/answers/296305-appdesigner-window-ends-up-in-background-after-uigetfile
            drawnow;
            figure(app.ChabocheParamFinder_3X_UIFigure);
            
            % Show file path
            app.CurrentFileEditField_3.Value = fullfile(path,file);
            
            % Send warning if a different Young Modulus is detected
            if ~isempty(app.Eyoung) && app.Eyoung ~= E
                msgbox('New acquisition has a different Young Modulus!','Warning','warn')
            end
            app.Eyoung = E;

            % Post-process ratcheting test
            [sigma_clean,e_clean,e_pl,maxvec_e,minvec_e,maxvec_e_pl,minvec_e_pl,maxvec_s,minvec_s] = RatCleanerFun(app,s,e);

            % Store ratcheting values
            app.e_rat = e_clean;
            app.e_pl_rat = e_pl;
            
            lenmax = length(maxvec_e_pl);
            lenmin = length(minvec_e_pl);
            len = min([lenmin,lenmax]);
            
            % Trim peak values
            maxvec_e_pl = maxvec_e_pl(1:len);
            minvec_e_pl = minvec_e_pl(1:len);

            % Store auxiliary variables
            app.delta_e_pl_av_rat = (maxvec_e_pl(1:end-1)+maxvec_e_pl(2:end)-2*minvec_e_pl(1:end-1))/2;
            app.delta_e_pl_s_rat = (maxvec_e_pl(2:end)+maxvec_e_pl(1:end-1))/2;
            
            app.cycle_rat = (1:length(app.delta_e_pl_av_rat))';
            app.max_e_rat = maxvec_e(1:length(app.cycle_rat));
            app.max_e_pl_rat = maxvec_e_pl(1:length(app.cycle_rat));
            
            app.max_sigma_rat = mean(maxvec_s(maxvec_s~=0));
            app.min_sigma_rat = mean(minvec_s(minvec_s~=0));
            app.sigma_av_rat = (app.max_sigma_rat+app.min_sigma_rat)/2;
            
            % Plot data
            cla(app.UIAxes_3)
            plot(app.UIAxes_3,app.cycle_rat,app.max_e_rat,'Color',[0 0.45 0.74],'LineWidth',2)
            hold(app.UIAxes_3,'on')
        end

        % Button pushed function: ClearDataButton_1
        function ClearDataButton_1Pushed(app, event)
            % Reset parameters and clear window
            resetParam(app,1);
            cla(app.UIAxes_1)
        end

        % Button pushed function: ClearDataButton_2
        function ClearDataButton_2Pushed(app, event)
            % Reset parameters and clear window
            resetParam(app,2);
            cla(app.UIAxes_2)
        end

        % Button pushed function: ClearDataButton_3
        function ClearDataButton_3Pushed(app, event)
            % Reset parameters and clear window
            resetParam(app,3);
            cla(app.UIAxes_3)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ChabocheParamFinder_3X_UIFigure = uifigure('Visible', 'off');
            app.ChabocheParamFinder_3X_UIFigure.Name = 'ChaPaFi v1.0';
            app.ChabocheParameterFinder3BackstressesRegularizedLSQLabel = uilabel(app.ChabocheParamFinder_3X_UIFigure);
            app.ChabocheParameterFinder3BackstressesRegularizedLSQLabel.Text = 'CHAboche kinematic hardening model PArameter FInder - v1.0';
            app.ExpCycle1Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle1Panel.Title = 'Experimental Cycle 1 (Larger)';
            app.UIAxes_1 = uiaxes(app.ExpCycle1Panel);
            xlabel(app.UIAxes_1, '\epsilon')
            ylabel(app.UIAxes_1, '\sigma [MPa]')
            app.ImportDataButton_1 = uibutton(app.ExpCycle1Panel, 'push');
            app.ImportDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_1Pushed, true);
            app.ImportDataButton_1.Text = 'Import Data';
            app.CurrentFileEditFieldLabel = uilabel(app.ExpCycle1Panel);
            app.CurrentFileEditFieldLabel.Text = 'Current File';
            app.CurrentFileEditField_1 = uieditfield(app.ExpCycle1Panel, 'text');
            app.CurrentFileEditField_1.Editable = 'off';
            app.ClearDataButton_1 = uibutton(app.ExpCycle1Panel, 'push');
            app.ClearDataButton_1.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_1Pushed, true);
            app.ClearDataButton_1.Text = 'Clear Data';
            app.ExpCycle2Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle2Panel.Title = 'Experimental Cycle 2 (Smaller)';
            app.UIAxes_2 = uiaxes(app.ExpCycle2Panel);
            xlabel(app.UIAxes_2, '\epsilon')
            ylabel(app.UIAxes_2, '\sigma [MPa]')
            app.CurrentFileEditField_2Label = uilabel(app.ExpCycle2Panel);
            app.CurrentFileEditField_2Label.Text = 'Current File';
            app.CurrentFileEditField_2 = uieditfield(app.ExpCycle2Panel, 'text');
            app.CurrentFileEditField_2.Editable = 'off';
            app.ImportDataButton_2 = uibutton(app.ExpCycle2Panel, 'push');
            app.ImportDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_2Pushed, true);
            app.ImportDataButton_2.Text = 'Import Data';
            app.ClearDataButton_2 = uibutton(app.ExpCycle2Panel, 'push');
            app.ClearDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_2Pushed, true);
            app.ClearDataButton_2.Text = 'Clear Data';
            app.ExpCycle3Panel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ExpCycle3Panel.Title = 'Ratcheting Test (Optional)';
            app.UIAxes_3 = uiaxes(app.ExpCycle3Panel);
            xlabel(app.UIAxes_3, 'N')
            ylabel(app.UIAxes_3, '\epsilon_{max}')
            app.CurrentFileEditField_3Label = uilabel(app.ExpCycle3Panel);
            app.CurrentFileEditField_3Label.Text = 'Current File';
            app.CurrentFileEditField_3 = uieditfield(app.ExpCycle3Panel, 'text');
            app.CurrentFileEditField_3.Editable = 'off';
            app.ImportDataButton_3 = uibutton(app.ExpCycle3Panel, 'push');
            app.ImportDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButton_3Pushed, true);
            app.ImportDataButton_3.Text = 'Import Data';
            app.ClearDataButton_3 = uibutton(app.ExpCycle3Panel, 'push');
            app.ClearDataButton_3.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButton_3Pushed, true);
            app.ClearDataButton_3.Text = 'Clear Data';
            app.IdentifyParametersButton = uibutton(app.ChabocheParamFinder_3X_UIFigure, 'push');
            app.IdentifyParametersButton.ButtonPushedFcn = createCallbackFcn(app, @IdentifyParametersButtonPushed, true);
            app.IdentifyParametersButton.Text = 'Identify Parameters';
            app.ResultsPanel = uipanel(app.ChabocheParamFinder_3X_UIFigure);
            app.ResultsPanel.Title = 'Results';
            app.sigma0EditFieldLabel = uilabel(app.ResultsPanel);
            app.sigma0EditFieldLabel.Text = 'sigma0';
            app.sigma0EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.sigma0EditField.ValueDisplayFormat = '%.3g';
            app.sigma0EditField.Editable = 'off';
            app.C_1Label = uilabel(app.ResultsPanel);
            app.C_1Label.Text = 'C1';
            app.C1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C1EditField.ValueDisplayFormat = '%.3g';
            app.C1EditField.Editable = 'off';
            app.g1EditFieldLabel = uilabel(app.ResultsPanel);
            app.g1EditFieldLabel.Text = 'g1';
            app.g1EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.g1EditField.ValueDisplayFormat = '%.3g';
            app.g1EditField.Editable = 'off';
            app.C3EditFieldLabel = uilabel(app.ResultsPanel);
            app.C3EditFieldLabel.Text = 'C3';
            app.C3EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C3EditField.ValueDisplayFormat = '%.3g';
            app.C3EditField.Editable = 'off';
            app.X3_0EditFieldLabel = uilabel(app.ResultsPanel);
            app.X3_0EditFieldLabel.Text = 'X3_0';
            app.X3_0EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.X3_0EditField.ValueDisplayFormat = '%.3g';
            app.X3_0EditField.Editable = 'off';
            app.C2EditFieldLabel = uilabel(app.ResultsPanel);
            app.C2EditFieldLabel.Text = 'C2';
            app.C2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.C2EditField.ValueDisplayFormat = '%.3g';
            app.C2EditField.Editable = 'off';
            app.g2EditFieldLabel = uilabel(app.ResultsPanel);
            app.g2EditFieldLabel.Text = 'g2';
            app.g2EditField = uieditfield(app.ResultsPanel, 'numeric');
            app.g2EditField.ValueDisplayFormat = '%.3g';
            app.g2EditField.Editable = 'off';
            app.SaveResultsButton = uibutton(app.ChabocheParamFinder_3X_UIFigure, 'push');
            app.SaveResultsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultsButtonPushed, true);
            app.SaveResultsButton.Text = 'Save Results';
            % Show the figure after all components are created
            app.ChabocheParamFinder_3X_UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChaPaFi_v1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ChabocheParamFinder_3X_UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ChabocheParamFinder_3X_UIFigure)
        end
    end
end